classdef MonteCarloSimulationGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        CancelButton               matlab.ui.control.Button
        SimulateButton             matlab.ui.control.Button
        TissueSizeEditField        matlab.ui.control.NumericEditField
        TissueSizeEditFieldLabel   matlab.ui.control.Label
        GridSizeEditField          matlab.ui.control.NumericEditField
        GridSizeEditFieldLabel     matlab.ui.control.Label
        PhotonCountEditField       matlab.ui.control.NumericEditField
        PhotonCountEditFieldLabel  matlab.ui.control.Label
        gEditField                 matlab.ui.control.NumericEditField
        gEditFieldLabel            matlab.ui.control.Label
        muSEditField               matlab.ui.control.NumericEditField
        muSEditFieldLabel          matlab.ui.control.Label
        MuAEditField               matlab.ui.control.NumericEditField
        MuAEditFieldLabel          matlab.ui.control.Label
        zEditField                 matlab.ui.control.NumericEditField
        zEditFieldLabel            matlab.ui.control.Label
        yEditField                 matlab.ui.control.NumericEditField
        yEditFieldLabel            matlab.ui.control.Label
        xEditField                 matlab.ui.control.NumericEditField
        xLabel                     matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
%Read inputs from UI components
x = app.xEditField.Value;
y = app.yEditField.Value;
z = app.zEditField.Value;
mu_a = app.MuAEditField.Value;
mu_s = app.muSEditField.Value;
g = app.gEditField.Value;
photonCount = app.PhotonCountEditField.Value;
gridSize = app.GridSizeEditField.Value;
tissueSize = app.TissueSizeEditField.Value;

% Call the simulation function
absorbedGrid = zeros(gridSize, gridSize, gridSize);
fluenceGrid = zeros(gridSize, gridSize, gridSize);
maxStepCount = 1000;

% Initialize photon paths
xPaths = cell(1, photonCount);
yPaths = cell(1, photonCount);
zPaths = cell(1, photonCount);

for photon = 1:photonCount
    [xPath, yPath, zPath, absorbedGrid, fluenceGrid] = simulatePhoton(x,y,z,mu_a,mu_s, mu_a + mu_s, g, maxStepCount, gridSize, tissueSize, absorbedGrid, fluenceGrid);
    
    xPaths{photon} = xPath;
    yPaths{photon} = yPath;
    zPaths{photon} = zPath;
end

% Visualize photon trajectories in the UIAxes
cla(app.UIAxes);  % Clear previous plots
hold(app.UIAxes, "on");
for photon = 1:photonCount
    plot3(app.UIAxes, xPaths{photon}, yPaths{photon}, zPaths{photon}, '.', 'MarkerSize', 8);
end
xlabel(app.UIAxes, 'X (mm)');
ylabel(app.UIAxes, 'Y (mm)');
zlabel(app.UIAxes, 'Z (mm)');
title(app.UIAxes, 'Photon Trajectories');
grid(app.UIAxes, 'on');
hold(app.UIAxes, "off");

% Enable interactive 3D rotation
rotate3d(app.UIAxes, 'on');


        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
delete(app.UIFigure);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.xLabel = uilabel(app.UIFigure);
            app.xLabel.Text = 'x';
            app.xEditField = uieditfield(app.UIFigure, 'numeric');
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.UIFigure, 'numeric');
            app.zEditFieldLabel = uilabel(app.UIFigure);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.UIFigure, 'numeric');
            app.MuAEditFieldLabel = uilabel(app.UIFigure);
            app.MuAEditFieldLabel.Text = 'MuA';
            app.MuAEditField = uieditfield(app.UIFigure, 'numeric');
            app.muSEditFieldLabel = uilabel(app.UIFigure);
            app.muSEditFieldLabel.Text = 'muS';
            app.muSEditField = uieditfield(app.UIFigure, 'numeric');
            app.gEditFieldLabel = uilabel(app.UIFigure);
            app.gEditFieldLabel.Text = 'g';
            app.gEditField = uieditfield(app.UIFigure, 'numeric');
            app.PhotonCountEditFieldLabel = uilabel(app.UIFigure);
            app.PhotonCountEditFieldLabel.Text = 'Photon Count';
            app.PhotonCountEditField = uieditfield(app.UIFigure, 'numeric');
            app.GridSizeEditFieldLabel = uilabel(app.UIFigure);
            app.GridSizeEditFieldLabel.Text = 'Grid Size';
            app.GridSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.TissueSizeEditFieldLabel = uilabel(app.UIFigure);
            app.TissueSizeEditFieldLabel.Text = 'Tissue Size';
            app.TissueSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MonteCarloSimulationGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end